classdef ea_discfiberexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ModelSetupDropDownLabel     matlab.ui.control.Label
        ModelSetupDropDown          matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        ColorButtonPositive         matlab.ui.control.Button
        ColorButtonNegative         matlab.ui.control.Button
        ShowPositiveFibersCheckBox  matlab.ui.control.CheckBox
        ShowNegativeFibersCheckBox  matlab.ui.control.CheckBox
        SplitColorbyGroupVariableCheckBox  matlab.ui.control.CheckBox
        ShowAmountSliderLabel       matlab.ui.control.Label
        ShowAmountSliderPositive    matlab.ui.control.Slider
        LeftSliderLabel             matlab.ui.control.Label
        LeftSliderPositive          matlab.ui.control.Slider
        RightSliderLabel            matlab.ui.control.Label
        RightSliderPositive         matlab.ui.control.Slider
        LeftSlider_2Label           matlab.ui.control.Label
        LeftSliderNegative          matlab.ui.control.Slider
        ShowAmountSlider_2Label     matlab.ui.control.Label
        ShowAmountSliderNegative    matlab.ui.control.Slider
        RightSlider_2Label          matlab.ui.control.Label
        RightSliderNegative         matlab.ui.control.Slider
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        VariableofInterestDropDown  matlab.ui.control.DropDown
        ColorBar                    matlab.ui.control.UIAxes
        CovariatesListBoxLabel      matlab.ui.control.Label
        CovariatesListBox           matlab.ui.control.ListBox
        Tab2                        matlab.ui.container.Tab
        ConnectomeDropDownLabel     matlab.ui.control.Label
        ConnectomeDropDown          matlab.ui.control.DropDown
        SaveTractResultsButton      matlab.ui.control.Button
        CalculateButton             matlab.ui.control.Button
        OpenLeadGroupProjectDiscriminativeFibertractButton  matlab.ui.control.Button
        PatientsListBoxLabel        matlab.ui.control.Label
        PatientsListBox             matlab.ui.control.ListBox
        IncludeallpatientsCheckBox  matlab.ui.control.CheckBox
        MirrorSidesCheckBox         matlab.ui.control.CheckBox
        ConnthresholdSlider         matlab.ui.control.Slider
        ConnthresholdLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        tractset % associated ea_discriminativetractset object.
        M % Lead group analysis model
    end
    
    methods (Access = private)
        
        function results = updateConnthresholdSlider(app,changingValue)
            value = app.ModelSetupDropDown.Value;
            switch value
                case 'T-Tests / VTAs' % ttests
                    app.ConnthresholdLabel.Text = {'Tracts should be connected to at',['least ',sprintf('%.1f',changingValue),' % of VTAs']};
                    app.ConnthresholdSlider.Limits = [0 100];
                    app.ConnthresholdSlider.Value = 20;
                case 'Spearman''s Correlation / Efields' % efield
                    app.ConnthresholdLabel.Text={'Tracts are modulated at E-Field',['strength of ',sprintf('%.1f',changingValue),' V/m']};
                    app.ConnthresholdSlider.Limits = [0 1000];
                    app.ConnthresholdSlider.Value = 150;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='Discriminative Fibers Explorer';
            
            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            app.ConnectomeDropDown.Items=mdl(sf==1);
            
            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            
            % Add Value to connthreshsliderlabel
            changingValue=app.ConnthresholdSlider.Value;
            updateConnthresholdSlider(app,changingValue);
            
        end

        % Value changed function: SplitColorbyGroupVariableCheckBox
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)
            value = app.SplitColorbyGroupVariableCheckBox.Value;
            switch value
                case 1
                    app.ShowNegativeFibersCheckBox.Enable = 'off';
                    app.ShowNegativeFibersCheckBox.Value = 0;
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    
                    app.ShowAmountSlider_2Label.Enable = 'off';
                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';
                case 0
                    app.ShowNegativeFibersCheckBox.Enable = 'on';
                    app.ColorButtonPositive.Enable = 'on';
            end
        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            changingValue=app.ConnthresholdSlider.Value;
            updateConnthresholdSlider(app,changingValue)
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            updateConnthresholdSlider(app,changingValue)
        end

        % Button pushed function: 
        % OpenLeadGroupProjectDiscriminativeFibertractButton
        function OpenLeadGroupProjectDiscriminativeFibertractButtonPushed(app, event)
            [FileName,PathName]=uigetfile('LEAD_groupanalysis.mat','Please Choose Lead-Group Analysis project...');
            pobj.leadgrouppath = fullfile(PathName,FileName);
            D=load(pobj.leadgrouppath); % produces M
            if isfield(D,'M') % Lead Group analysis path loaded
                
                app.M=D.M;
                clear D
                % Update GUI:
                app.OpenLeadGroupProjectDiscriminativeFibertractButton.Text=pobj.leadgrouppath;
                
                app.PatientsListBox.Items=app.M.patient.list;
                app.VariableofInterestDropDown.Items=app.M.clinical.labels;
                app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.M.clinical.labels];
                [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
                app.CovariatesListBox.Items(ix)=[];
                app.PatientsListBox.Value=app.PatientsListBox.Items; % select all
                
            else % saved tractobject loaded
                keyboard
            end
            
            % enable controls:
            app.ModelSetupDropDown.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.IncludeallpatientsCheckBox.Enable = 'on';
            app.MirrorSidesCheckBox.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.VariableofInterestDropDown.Enable = 'on';
            app.CovariatesListBox.Enable = 'on';
            app.SplitColorbyGroupVariableCheckBox.Enable = 'on';
            app.ShowPositiveFibersCheckBox.Enable = 'on';
            app.ShowNegativeFibersCheckBox.Enable = 'on';
            app.ShowAmountSliderPositive.Enable = 'on';
            app.LeftSliderPositive.Enable = 'on';
            app.RightSliderPositive.Enable = 'on';
            app.ColorButtonPositive.Enable = 'on';
            app.CalculateButton.Enable = 'on';                        
            app.ShowNegativeFibersCheckBox.Enable = 'on';
            
            app.ConnthresholdSlider.Enable = 'on';
            app.ConnthresholdLabel.Enable = 'on';
            app.ModelSetupDropDownLabel.Enable = 'on';
            app.ShowAmountSliderLabel.Enable = 'on';
            app.LeftSliderLabel.Enable = 'on';
            app.RightSliderLabel.Enable = 'on';
            app.VariableofInterestDropDownLabel.Enable = 'on';
            app.CovariatesListBoxLabel.Enable = 'on';

        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            figure;
            pobj.M=app.M;
            pobj.app=app;
            pobj.leadgrouppath=app.OpenLeadGroupProjectDiscriminativeFibertractButton.Text;
            app.tractset=ea_disctractset(pobj); % initialize tractset object.
            
            app.tractset.draw;


                         app.SaveTractResultsButton.Enable = 'on';

        end

        % Value changed function: IncludeallpatientsCheckBox
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
        switch value
            case 1
                app.PatientsListBox.Enable='off';
                app.PatientsListBox.Value=app.PatientsListBox.Items;
            case 0
                app.PatientsListBox.Enable='on';
        end
        end

        % Value changed function: ShowPositiveFibersCheckBox
        function ShowPositiveFibersCheckBoxValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox.Value;
            switch value
                case 0
                    app.ShowAmountSliderPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.LeftSliderPositive.Enable = 'off';
                    app.RightSliderPositive.Enable = 'off';
                    
                    app.ShowAmountSliderLabel.Enable = 'off';
                    app.LeftSliderLabel.Enable = 'off';
                    app.RightSliderLabel.Enable = 'off';
                case 1
                    app.ShowAmountSliderPositive.Enable = 'on';
                    app.ColorButtonPositive.Enable = 'on';
                    app.LeftSliderPositive.Enable = 'on';
                    app.RightSliderPositive.Enable = 'on';
                    
                    app.ShowAmountSliderLabel.Enable = 'on';
                    app.LeftSliderLabel.Enable = 'on';
                    app.RightSliderLabel.Enable = 'on';
            end

            
        end

        % Value changed function: ShowNegativeFibersCheckBox
        function ShowNegativeFibersCheckBoxValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox.Value;
            switch value
                case 0
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';
                    
                    
                    app.ShowAmountSlider_2Label.Enable = 'off';
                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';
                    
                case 1
                    app.ShowAmountSliderNegative.Enable = 'on';
                    app.ColorButtonNegative.Enable = 'on';
                    app.LeftSliderNegative.Enable = 'on';
                    app.RightSliderNegative.Enable = 'on';
                    
                    app.ShowAmountSlider_2Label.Enable = 'on';
                    app.LeftSlider_2Label.Enable = 'on';
                    app.RightSlider_2Label.Enable = 'on';
                    
                    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ModelSetupDropDownLabel = uilabel(app.UIFigure);
            app.ModelSetupDropDownLabel.Enable = 'off';
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.UIFigure);
            app.ModelSetupDropDown.Items = {'T-Tests / VTAs', 'Spearman''s Correlation / Efields'};
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Enable = 'off';
            app.ModelSetupDropDown.Value = 'T-Tests / VTAs';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ColorButtonPositive = uibutton(app.Tab, 'push');
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.ColorButtonNegative = uibutton(app.Tab, 'push');
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.Tab);
            app.ShowPositiveFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBoxValueChanged, true);
            app.ShowPositiveFibersCheckBox.Enable = 'off';
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox.Value = true;
            app.ShowNegativeFibersCheckBox = uicheckbox(app.Tab);
            app.ShowNegativeFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBoxValueChanged, true);
            app.ShowNegativeFibersCheckBox.Enable = 'off';
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.SplitColorbyGroupVariableCheckBox = uicheckbox(app.Tab);
            app.SplitColorbyGroupVariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitColorbyGroupVariableCheckBoxValueChanged, true);
            app.SplitColorbyGroupVariableCheckBox.Enable = 'off';
            app.SplitColorbyGroupVariableCheckBox.Text = 'Split & Color by Group Variable';
            app.ShowAmountSliderLabel = uilabel(app.Tab);
            app.ShowAmountSliderLabel.Enable = 'off';
            app.ShowAmountSliderLabel.Text = 'Show Amount';
            app.ShowAmountSliderPositive = uislider(app.Tab);
            app.ShowAmountSliderPositive.Enable = 'off';
            app.LeftSliderLabel = uilabel(app.Tab);
            app.LeftSliderLabel.Enable = 'off';
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSliderPositive = uislider(app.Tab);
            app.LeftSliderPositive.Enable = 'off';
            app.RightSliderLabel = uilabel(app.Tab);
            app.RightSliderLabel.Enable = 'off';
            app.RightSliderLabel.Text = 'Right';
            app.RightSliderPositive = uislider(app.Tab);
            app.RightSliderPositive.Enable = 'off';
            app.LeftSlider_2Label = uilabel(app.Tab);
            app.LeftSlider_2Label.Enable = 'off';
            app.LeftSlider_2Label.Text = 'Left';
            app.LeftSliderNegative = uislider(app.Tab);
            app.LeftSliderNegative.Enable = 'off';
            app.ShowAmountSlider_2Label = uilabel(app.Tab);
            app.ShowAmountSlider_2Label.Enable = 'off';
            app.ShowAmountSlider_2Label.Text = 'Show Amount';
            app.ShowAmountSliderNegative = uislider(app.Tab);
            app.ShowAmountSliderNegative.Enable = 'off';
            app.RightSlider_2Label = uilabel(app.Tab);
            app.RightSlider_2Label.Enable = 'off';
            app.RightSlider_2Label.Text = 'Right';
            app.RightSliderNegative = uislider(app.Tab);
            app.RightSliderNegative.Enable = 'off';
            app.VariableofInterestDropDownLabel = uilabel(app.Tab);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.Tab);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.ColorBar = uiaxes(app.Tab);
            title(app.ColorBar, 'Colorbar')
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.CovariatesListBoxLabel = uilabel(app.Tab);
            app.CovariatesListBoxLabel.Enable = 'off';
            app.CovariatesListBoxLabel.Text = 'Covariates';
            app.CovariatesListBox = uilistbox(app.Tab);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ConnectomeDropDownLabel = uilabel(app.UIFigure);
            app.ConnectomeDropDownLabel.Enable = 'off';
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.UIFigure);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.Enable = 'off';
            app.ConnectomeDropDown.Value = '';
            app.SaveTractResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveTractResultsButton.Enable = 'off';
            app.SaveTractResultsButton.Text = 'Save Tract Results';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.OpenLeadGroupProjectDiscriminativeFibertractButton = uibutton(app.UIFigure, 'push');
            app.OpenLeadGroupProjectDiscriminativeFibertractButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLeadGroupProjectDiscriminativeFibertractButtonPushed, true);
            app.OpenLeadGroupProjectDiscriminativeFibertractButton.Text = 'Open Lead-Group Project / Discriminative Fibertract';
            app.PatientsListBoxLabel = uilabel(app.UIFigure);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.UIFigure);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.IncludeallpatientsCheckBox = uicheckbox(app.UIFigure);
            app.IncludeallpatientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeallpatientsCheckBoxValueChanged, true);
            app.IncludeallpatientsCheckBox.Enable = 'off';
            app.IncludeallpatientsCheckBox.Text = 'Include all patients';
            app.IncludeallpatientsCheckBox.Value = true;
            app.MirrorSidesCheckBox = uicheckbox(app.UIFigure);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields to contralateral sides';
            app.ConnthresholdSlider = uislider(app.UIFigure);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.UIFigure);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = ea_discfiberexplorer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end