classdef lead_import < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeadMigrateUIFigure           matlab.ui.Figure
        RecentDatasetsDropDown        matlab.ui.control.DropDown
        RegeneraterawimagejsonButton  matlab.ui.control.Button
        BusyLamp                      matlab.ui.control.Lamp
        SelectoutputdatasetdirectoryButton  matlab.ui.control.Button
        ConvertDICOMdatatoBIDScompliantdatasetCheckBox  matlab.ui.control.CheckBox
        SelectpatientsButton          matlab.ui.control.Button
        MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox  matlab.ui.control.CheckBox
        ConverttoDicomusingDropDown   matlab.ui.control.DropDown
        ConverttoDicomusingDropDownLabel  matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        source_folder % Description
        output_folder
        input_label
        output_label
        close_flag
        handles
        options
        subjID = {};
        notMigrated = {};
        user_dicom_conv = 0;
        
    end
    
    methods (Access = public)
        
        
        function result = checkIfOneExist(~,cell_of_filenames,str_to_match)
            result = 0;
            %regexp returns an array of cells ({0} = does not match str,
            %{[1]} = matches the str).
            %isempty returns an array of 1 when the regexp is not
            %satisfied. Inverse of the cell fun will return 1 if the regexp
            %matches the str, and 0 if regexp does not match the str
            %any will check if there is atleast one pattern has matched
            if any(~cellfun('isempty',regexpi(cell_of_filenames,str_to_match)))
                result = 1;
            end
        end
       
        function result = checkIfNoneExist(app,cell_of_filenames,str_to_match)
            output = checkIfOneExist(app,cell_of_filenames,str_to_match);
            result = ~output;
        end
        
        function result = checkIfOnlyExist(~,cell_of_filenames,str_to_match)
            result = 0;
            if all(~cellfun('isempty',regexpi(cell_of_filenames,str_to_match)))
                result = 1;
            end
        end
        
        function count = checkValidity(app,folders,flag)
            
            count=0;
            for i=1:length(folders)
                path=folders{i};
                if isempty(path)
                    return;
                end
                found = ea_checkSpecialChars(path);
                [dataset_path,dataset_folder,~] = fileparts(path);
                is_underscore = regexp(dataset_folder,'.*_|-|\s.*','match');
                if ~isempty(is_underscore) 
                    if strcmp(flag,'output')
                        new_dataset_folder = regexprep(dataset_folder, '[\W_]', '');
                        movefile(fullfile(dataset_path,dataset_folder),fullfile(dataset_path,new_dataset_folder));
                        app.output_folder{i} = fullfile(dataset_path,new_dataset_folder);
                    end
                    warning("It looks like you have special chars in your dataset folder name: %s. We will use a removed version for BIDS conversion",dataset_folder);
                    found = 0;
                end
                if found 
                   count=count+1;
                end
            end
            
        end


        function is_member_flag = ea_ismember(~,cellA,stringB)
            %%this function returns a boolean value instead of a cell
            is_member_flag = 0;
            if ~iscell(cellA)
                ea_error("You have to supply a cell as the first argument");
            end
            if ~isstring(stringB)
                ea_error("You have to supply a string as the second argument");
            end
            cell_output = ismember(cellA,stringB);
            boolen_value = find(cell_output);
            if length(boolen_value) >= 1
                is_member_flag = 1;
            end

            return
        end

        function [results,doOnlyRaw] = enablerules(app,selection)
            
            subfolder = dir_without_dots(selection);
            subfolder = {subfolder.name};
            results = 'pass';
            % detection function: if the patient has derivatives and
            % raw data, but not in BIDS format then it should be migrated.            
            if checkIfOneExist(app,subfolder,'^glanat*.nii$')
                doOnlyRaw = 0;
            elseif checkIfOneExist(app,subfolder,'^ea_.*.mat$') || ea_ismember(app,subfolder,"current_headmodel") || ea_ismember(app,subfolder,"stimulation") % for detached files only, experimental
                doOnlyRaw = 0;
            else
                doOnlyRaw = 1;
            end
                % both dicom & derivatives available. Requires special
                % handling in the migrate code.
        end
        function op_label(app,flag,source_pt)
            if strcmp(flag,'source')
                app.SelectpatientsButton.Text = source_pt;
                app.SelectpatientsButton.FontColor = 'g';
            end
            if strcmp(flag,'multsource')
                app.SelectpatientsButton.Text = ['Loaded Multiple Patients(' num2str(length(app.source_folder)) ')'];
                app.SelectpatientsButton.FontColor = 'g';
                app.SelectpatientsButton.FontColor = 'g';
            end
            if strcmp(flag,'dest')
                app.SelectoutputdatasetdirectoryButton.Text = app.output_folder{1};
                app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
            end
        end

        function reset_rules(app)
            
            app.RunButton.Enable = 'on';
            app.ConverttoDicomusingDropDown.Enable = 'on';
            app.ConverttoDicomusingDropDownLabel.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'on';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
            app.SelectpatientsButton.Text = '';
        end


        function runImport(~,run_prop)  
            source_dir = run_prop.source;
            dest_folder = run_prop.dest;
            ea_legacy2bids(source_dir,dest_folder,0,0);
        end


        function setFlags(app,flag)
            if strcmp(flag,'onlyDCMconv')
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
            elseif strcmp(flag,'onlyMigrate')
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
            elseif strcmp(flag,'MigrateDCMconv')
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
            end
        end

        function [bidsroot,subjID] = generateopparams(app,dest)
            bidsroot = dest;
            subjID = app.subjID;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source_folder, options, handles, dest_folder)
            if isfile([ea_getearoot, 'common', filesep, 'ea_recentpatients.mat'])
                load([ea_getearoot, 'common', filesep, 'ea_recentpatients.mat'], 'recentfolders');
                if ~strcmp('No recent patients found', recentfolders)
                    recentfolders = regexprep(recentfolders, ['\', filesep, 'derivatives.*$'], '');
                    recentfolders = ['Recent Datasets'; unique(recentfolders)];
                    app.RecentDatasetsDropDown.Items = recentfolders;
                    app.RecentDatasetsDropDown.Value = 'Recent Datasets';
                end
            end
            app.LeadMigrateUIFigure.Name = 'Lead Import';
            app.LeadMigrateUIFigure.HandleVisibility = 'on';
            app.SelectpatientsButton.FontColor = 'r';            

        end

        % Button pushed function: SelectpatientsButton
        function SelectpatientsButtonPushed(app, event)
            reset_rules(app)
            app.BusyLamp.Color = 'red';
            app.source_folder = ea_uigetdir(pwd);
            if isempty(app.source_folder)
                return;
            end
            figure(app.LeadMigrateUIFigure);
            count = checkValidity(app,app.source_folder,'source');
            if count >= 1
                return
            end
            
            app.SelectpatientsButton.Tooltip = app.source_folder;
            if length(app.source_folder) > 1
                op_label(app,'multsource');
            else
                op_label(app,'source',app.source_folder{1})
            end
            app.BusyLamp.Color = 'green';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;

        end

        % Value changed function: 
        % ConvertDICOMdatatoBIDScompliantdatasetCheckBox
        function ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
         
            app.BusyLamp.Color = 'green';
        end

        % Value changed function: ConverttoDicomusingDropDown
        function ConverttoDicomusingDropDownValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConverttoDicomusingDropDown.Value;
            app.BusyLamp.Color = 'green';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
             app.BusyLamp.Color = 'red';
             if isempty(app.output_folder)
                 errordlg("No output directory selected, select to continue...",'Output directory error')
             else
                 for pt = 1:length(app.source_folder)
                     source_dir = app.source_folder{pt};
                     dest_folder = app.output_folder{1};
                     [result,doOnlyRaw] = enablerules(app,source_dir);
                     if strcmp(result,'pass')
                        if doOnlyRaw
                            ea_legacy2bids(source_dir,dest_folder,1);
                        else
                            ea_legacy2bids(source_dir,dest_folder,0);
                        end
                     else
                         disp(['Patient migration failed:%s',app.source_folder{pt}])
                         disp("Skipping for now...")
                         continue
                     end
                 end
             end
             disp('Process done ******');
        end

        % Callback function
        function MigrateOptionsButtonGroupSelectionChanged(app, event)
           
        end

        % Value changed function: 
        % MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox
        function MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged(app, event)
            value = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;

        end

        % Button pushed function: SelectoutputdatasetdirectoryButton
        function SelectoutputdatasetdirectoryButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            selpath = uigetdir(pwd);
            if selpath == 0
                return
            end
            figure(app.LeadMigrateUIFigure);
            app.output_folder = {selpath};
            count = checkValidity(app,app.output_folder,'output');
            if count >= 1
                return
            end


            op_label(app,'dest');
            
            app.SelectoutputdatasetdirectoryButton.Tooltip = app.output_folder;
            app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
            app.SelectoutputdatasetdirectoryButton.FontWeight = 'bold';
            if ~isempty(app.source_folder) && ~isempty(app.output_folder)
                app.RunButton.Enable = 'on';
            end
            app.BusyLamp.Color = 'green';
        end

        % Button pushed function: RegeneraterawimagejsonButton
        function RegeneraterawimagejsonButtonPushed(app, event)
            rawdata_folder = fullfile(app.source_folder,'rawdata');
            for i=1:length(rawdata_folder)
                subj_name = dir_without_dots(rawdata_folder{i});
                subj_name = subj_name([subj_name.isdir]);
                subj_name = subj_name.name;
                prefs_dir = fullfile(app.source_folder{i},'derivatives','leaddbs',subj_name,'prefs');
                if ~exist(prefs_dir,'dir')
                    mkdir(prefs_dir)
                end
                raw_preop_dir = fullfile(rawdata_folder{i},subj_name,'ses-preop','anat');
                raw_postop_dir = fullfile(rawdata_folder{i},subj_name,'ses-postop','anat');
                opt.FileName = fullfile(prefs_dir,[subj_name,'_','desc-rawimages.json']);
                
                preop_files = dir_without_dots(raw_preop_dir);
                preop_files = {preop_files.name};
                
                if ~isempty(preop_files)
                    for j=1:length(preop_files)
                        json_val = regexprep(preop_files{j},'(.nii)|(.gz)','');
                        if contains(preop_files{j},'acq-')
                            temp_tag = strsplit(json_val,'-');
                            modality_str = temp_tag{end};
                            rawdata_fieldname = modality_str;
                            anat_files_selected.preop.anat.(rawdata_fieldname) = json_val;
                        else
                            temp_tag = strsplit(json_val,'preop_');
                            rawdata_fieldname = temp_tag{end};
                            anat_files_selected.preop.anat.(rawdata_fieldname) = json_val;
                        end
                    end
                end
                postop_files = dir_without_dots(raw_postop_dir);
                postop_files = {postop_files.name};
                
                for k=1:length(postop_files)
                    json_val = regexprep(postop_files{k},'(.nii)|(.gz)','');
                    if contains(postop_files{k},'ct','IgnoreCase',true)
                        rawdata_fieldname = 'CT';
                        anat_files_selected.postop.anat.(rawdata_fieldname) = json_val;
                    else
                        temp_tag = strsplit(json_val,'-');
                        rawdata_fieldname = temp_tag{end};
                        anat_files_selected.postop.anat.(rawdata_fieldname) = json_val;
                    end
                end
                savejson('',anat_files_selected,opt);
            end   
        end

        % Callback function
        function RerunButtonPushed(app, event)
           

        end

        % Value changed function: RecentDatasetsDropDown
        function RecentDatasetsDropDownValueChanged(app, event)
            value = app.RecentDatasetsDropDown.Value;
            if ~strcmp(value, 'Recent Datasets')
                app.output_folder = {value};
                op_label(app, 'dest');
                output_message = app.output_folder{1};
            
                if isempty(app.output_label)
                    app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
                else
                    app.output_label.Label.Text = '';
                    app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
                end
                app.SelectoutputdatasetdirectoryButton.Tooltip = app.output_folder;
                app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
                app.SelectoutputdatasetdirectoryButton.FontWeight = 'bold';
                if ~isempty(app.source_folder) && ~isempty(app.output_folder)
                    app.RunButton.Enable = 'on';
                end
                app.BusyLamp.Color = 'green';
            end
        end

        % Close request function: LeadMigrateUIFigure
        function LeadMigrateUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function SkipDICOMconversiononlyMigratelegacyCheckBoxValueChanged(app, event)
            value = app.SkipDICOMconversiononlyMigratelegacyCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeadMigrateUIFigure = uifigure('Visible', 'off');
            app.LeadMigrateUIFigure.Name = 'Lead Migrate';
            app.LeadMigrateUIFigure.CloseRequestFcn = createCallbackFcn(app, @LeadMigrateUIFigureCloseRequest, true);
            app.RunButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ConverttoDicomusingDropDownLabel = uilabel(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDownLabel.Visible = 'off';
            app.ConverttoDicomusingDropDownLabel.Text = 'Convert to Dicom using..';
            app.ConverttoDicomusingDropDown = uidropdown(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDown.Items = {'dcm2niix', 'dicm2nii', 'SPM'};
            app.ConverttoDicomusingDropDown.ValueChangedFcn = createCallbackFcn(app, @ConverttoDicomusingDropDownValueChanged, true);
            app.ConverttoDicomusingDropDown.Visible = 'off';
            app.ConverttoDicomusingDropDown.Value = 'dcm2niix';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged, true);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Text = 'Migrate legacy Lead-DBS dataset to BIDS dataset';
            app.SelectpatientsButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectpatientsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpatientsButtonPushed, true);
            app.SelectpatientsButton.Text = 'Select patient(s)';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged, true);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Visible = 'off';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Text = 'Convert DICOM data to BIDS compliant dataset';
            app.SelectoutputdatasetdirectoryButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectoutputdatasetdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputdatasetdirectoryButtonPushed, true);
            app.SelectoutputdatasetdirectoryButton.Text = 'Select output dataset directory';
            app.BusyLamp = uilamp(app.LeadMigrateUIFigure);
            app.RegeneraterawimagejsonButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.RegeneraterawimagejsonButton.ButtonPushedFcn = createCallbackFcn(app, @RegeneraterawimagejsonButtonPushed, true);
            app.RegeneraterawimagejsonButton.Visible = 'off';
            app.RegeneraterawimagejsonButton.Text = 'Regenerate rawimage.json';
            app.RecentDatasetsDropDown = uidropdown(app.LeadMigrateUIFigure);
            app.RecentDatasetsDropDown.Items = {'Recent Datasets'};
            app.RecentDatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @RecentDatasetsDropDownValueChanged, true);
            app.RecentDatasetsDropDown.Tooltip = {'Recent Datasets'};
            app.RecentDatasetsDropDown.Value = 'Recent Datasets';
            % Show the figure after all components are created
            app.LeadMigrateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lead_import(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeadMigrateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeadMigrateUIFigure)
        end
    end
end